classdef gameplay_public < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UITable                matlab.ui.control.Table
        UITable2               matlab.ui.control.Table
        RollButton_2           matlab.ui.control.Button
        RollButton             matlab.ui.control.Button
        EndTurnButton_2        matlab.ui.control.Button
        EndTurnButton_3        matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        ScoreEditFieldLabel    matlab.ui.control.Label
        ScoreEditField         matlab.ui.control.NumericEditField
        ScoreEditField_2Label  matlab.ui.control.Label
        ScoreEditField_2       matlab.ui.control.NumericEditField
        RollAgainButton_3      matlab.ui.control.Button
        Image3                 matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        RollAgainButton_2      matlab.ui.control.Button
        Player1EditFieldLabel  matlab.ui.control.Label
        Player1EditField       matlab.ui.control.EditField
        Player2EditFieldLabel  matlab.ui.control.Label
        Player2EditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap

        myPlayerNum 
        otherPlayerNum 
        channelID 
        writeKey 
        readKey   
        readDelay 
        writeDelay  
        roll  
        myLastRoll  
        otherLastRoll 
   
    end

    methods (Access = private)
    
        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;
            
            % Update plot 
            Z = peaks(sz);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,c);
            
            % Re-enable the Plot Options button
            app.OptionsButton.Enable = 'on';
        end

          function [] = WaitForOtherPlayer(app)
            playerWhoSentMsg = 0;
            
            while playerWhoSentMsg ~= app.otherPlayerNum
                % read a data table from the channel
                dataTable = thingSpeakRead(app.channelID, 'Fields', 1, 'ReadKey', ...
                    app.readKey, 'OutputFormat','Table');
                              
                % access the entry in the table containing the data string
                dataString = string(dataTable.dataString(1));
                
                % to help with debugging
                disp("I am player " + num2str(app.myPlayerNum) ...
                    + " and I just read this data from the ThingSpeak channel:");
                disp(dataString);
                
                % split data string into separate numbers
                dataParts = split(strip(dataString));
                
                % the first number in the string should 
                % always be the number of the player who
                % last wrote the data
                playerWhoSentMsg = str2num(dataParts(1));
                
                % delay before trying to read the online channel again
                pause(app.readDelay);              
            end
        end     
        
        function [] = ClearThinkSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', "0", 'WriteKey', app.writeKey);
        end

         function [] = SendDataToOtherPlayer(app)
            pause(app.writeDelay);
            dataString = strcat(string(app.myPlayerNum), " ", ...
                string(app.myLastRoll.suit), " ", ...
                string(app.myLastRoll.num), " ");
            
            rollString = app.MakeStringFromRoll(app.roll);
            dataString = strcat(dataString, rollString);
            
            disp("I sent this data to the ThingSpeak channel: ");
            disp(dataString);
            
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', dataString ...
                ,'WriteKey', app.writeKey); 
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
        set(app.Image,'visible','on');
        set(app.Image2,'visible','on');
        set(app.Image3,'visible','off');
        set(app.Image4,'visible','off');

        app.myPlayerNum = 1;
        app.otherPlayerNum = 2;

        app.channelID = 1580913;
        app.writeKey = P8W9ZHP2WOD6RP3Y;
        app.readKey = I8DVSH0CY0H0AV1X;

        app.readDelay = 5;
        app.writeDelay = 1;
        app.ClearThinkSpeakChannel();
        set(app.RollButton, 'Enable', 'off');
        
           %%% Must fix following
            % if app.snakeyes.player1 = valuechanged
            %    app.myPlayerNum = 1;
            %    app.otherPlayerNum = 2;
            % else
            %    app.myPlayerNum = 2;
            %   app.otherPlayerNum = 1;
            % end

        end

        % Callback function
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogAppExample(app, app.CurrentSize, app.CurrentColormap);
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: RollButton_2
        function RollButton_2Pushed(app, event)
          gameScore = randi(1,1, 12)
          
          gameScore = rand(gameScore)
            display(gameScore);
            app.ScoreEditField.Value = gameScore
             [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','off');
        set(app.Image4,'visible','on');
        end

        % Value changed function: ScoreEditField
        function ScoreEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            gameScore = randi(1,1, 12)
          
          gameScore = rand(gameScore)
            display(gameScore);
            app.ScoreEditField_2.Value = gameScore
             [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','on');
        set(app.Image4,'visible','off');
        
            if (app.myPlayerNum == 1)
                set(app.RollButton, 'Enable', 'on');
                set(app.RollAgainButton_2, 'Enable', 'on')
                set(app.EndTurnButton_2, 'Enable', 'on')
                
            else % app.myPlayerNum == 2
                app.WaitForOtherPlayer();
                set(app.RollButton_2, 'Enable', 'on');
                set(app.RollAgainButton_3, 'Enable', 'on' );
                set(app.EndTurnButton_3, 'Enable', 'on');
            end    
        end

        % Value changed function: ScoreEditField_2
        function ScoreEditField_2ValueChanged(app, event)
         
        end

        % Button pushed function: EndTurnButton_3
        function EndTurnButton_3Pushed(app, event)
            endgamescreen_exported
            
             [y,Fs]=audioread("endGame.wav")
            sound(y,Fs)
        end

        % Button pushed function: EndTurnButton_2
        function EndTurnButton_2Pushed(app, event)
            endgamescreen_exported

             [y,Fs]=audioread("endGame.wav")
            sound(y,Fs)
        end

        % Button pushed function: RollAgainButton_3
        function RollAgainButton_3Pushed(app, event)
          [y,Fs]=audioread("MANYDICE.wav")
           sound(y,Fs)

           set(app.Image3,'visible','off');
        set(app.Image4,'visible','on');
        end

        % Button pushed function: RollAgainButton_2
        function RollAgainButton_2Pushed(app, event)
           [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','on');
        set(app.Image4,'visible','off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Display Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Round'; 'Score'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Round'; 'Score'};
            app.UITable2.RowName = {};
            app.RollButton_2 = uibutton(app.UIFigure, 'push');
            app.RollButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollButton_2Pushed, true);
            app.RollButton_2.Text = 'Roll';
            app.RollButton = uibutton(app.UIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.EndTurnButton_2 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_2.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_2Pushed, true);
            app.EndTurnButton_2.Text = 'End Turn';
            app.EndTurnButton_3 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_3.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_3Pushed, true);
            app.EndTurnButton_3.Text = 'End Turn';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SnakeIcon2.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'SnakeIcon.jpg';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score:';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreEditFieldValueChanged, true);
            app.ScoreEditField_2Label = uilabel(app.UIFigure);
            app.ScoreEditField_2Label.Text = 'Score:';
            app.ScoreEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField_2.ValueChangedFcn = createCallbackFcn(app, @ScoreEditField_2ValueChanged, true);
            app.RollAgainButton_3 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_3.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_3Pushed, true);
            app.RollAgainButton_3.Text = 'Roll Again';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'dice_gif.gif';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'dice_gif.gif';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_2Pushed, true);
            app.RollAgainButton_2.Text = 'Roll Again';
            app.Player1EditFieldLabel = uilabel(app.UIFigure);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1EditField = uieditfield(app.UIFigure, 'text');
            app.Player2EditFieldLabel = uilabel(app.UIFigure);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameplay_public(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end