classdef rules < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RulesLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        StartGameButton            matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Player1NameEditFieldLabel  matlab.ui.control.Label
        Player1NameEditField       matlab.ui.control.EditField
        Player2NameEditFieldLabel  matlab.ui.control.Label
        Player2NameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
       Player1Name
       Player2Name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            % Open gameplay screen and close Rules
            gameplay_public
            close(app.UIFigure)

            % Audio commands
            [y,Fs] = audioread("snakeHiss.wav");
            sound(y,Fs)
        end

        % Value changed function: Player1NameEditField
        function Player1NameEditFieldValueChanged(app, event)
            %global player1Name
            app.Player1Name = app.Player1NameEditField.Value;
        end

        % Value changed function: Player2NameEditField
        function Player2NameEditFieldValueChanged(app, event)
            %global player2Name
            app.Player2Name = app.Player2NameEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RulesLabel = uilabel(app.UIFigure);
            app.RulesLabel.Text = 'Rules ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'A player starts off by rolling two dice. The numbers on the dice are added and the player can choose whether to roll again. If they roll again, the new numbers are added to the old score. The player’s turn ends when they decide to stop rolling, or they roll a one. If one one is rolled, the player’s score for that turn is set to zero, and their turn ends. If two ones are rolled (snake eyes), the player’s score is set to zero for that round and all previous rounds, and the player’s turn ends. The game can be played with any number of players. Five rounds are played. At the end, players points are added up and the player with the most wins. ';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'snakeGif.gif';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'SnakeEyesGif.gif';
            app.Player1NameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1NameEditFieldLabel.Text = 'Player 1 Name';
            app.Player1NameEditField = uieditfield(app.UIFigure, 'text');
            app.Player1NameEditField.ValueChangedFcn = createCallbackFcn(app, @Player1NameEditFieldValueChanged, true);
            app.Player2NameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2NameEditFieldLabel.Text = 'Player 2 Name';
            app.Player2NameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2NameEditField.ValueChangedFcn = createCallbackFcn(app, @Player2NameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rules

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end